classdef AirQuality < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AirQualityMonitorUIFigure     matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        DaysForecastPanel             matlab.ui.container.Panel
        LocalCurrentConditionsPanel   matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        UITable                       matlab.ui.control.Table
        GridLayout3                   matlab.ui.container.GridLayout
        AirqualityLabel               matlab.ui.control.Label
        UpdateButton                  matlab.ui.control.Button
        LocationUSCityEditField       matlab.ui.control.EditField
        LocationUSCityEditFieldLabel  matlab.ui.control.Label
        AirQualityMonitorLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        AppSize = [900 620] % Figure size
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            g = groot;
            app.AirQualityMonitorUIFigure.Position = [(g.ScreenSize(3)-app.AppSize(1))/2 (g.ScreenSize(4)-app.AppSize(2))/2 app.AppSize];
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % Read data from web API and predict
                        % Wait bar
            d = uiprogressdlg(app.AirQualityMonitorUIFigure,'Title','Please wait','Message','Updating...',"Indeterminate","on");
            try
                [Label,~,~,~, CurrentData, ForecastData] = CurrentAirQual(app.LocationUSCityEditField.Value);
                
                switch Label
                    case "Good"
                        app.AirqualityLabel.FontColor = [0.298 0.4902 0.0431]; % green
                    case "Moderate"
                        app.AirqualityLabel.FontColor = [0.9290 0.6940 0.1250]; % yellow
                    case "Unhealthy for sensitive groups"
                        app.AirqualityLabel.FontColor = [0.8500 0.3250 0.0980]; %orange/red
                    case "Unhealthy"
                        app.AirqualityLabel.FontColor = [0.75 0 0];  % red
                    case "Very unhealthy"
                        app.AirqualityLabel.FontColor = [0.6350 0.0780 0.1840];  % dark red
                    case "Hazardous"
                        app.AirqualityLabel.FontColor = [0.70 0.0780 0.1840];  % dark red
                end
                
                % Visualize
                stackedplot(app.DaysForecastPanel, ForecastData,"FontSize",12,"LineWidth",2,"FontName","Calibri Light","GridVisible","on","Color",[0.298 0.4902 0.0431])
                
                % Update table showing current weather info
                app.UITable.Data = CurrentData(:,["T","P","DP","RH","WindDir","WindSpd"]);
                % Display result, use color to indicate air quality
                app.AirqualityLabel.Text = "Air Quality: " + Label;
                app.AirqualityLabel.Visible = true;
                app.DaysForecastPanel.Visible = true;
                close(d)
            catch
                close(d)
                uialert(app.AirQualityMonitorUIFigure,"Unknown location. Please select a different city.","Unknown Location")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AirQualityMonitorUIFigure = uifigure('Visible', 'off');
            app.AirQualityMonitorUIFigure.Name = 'Air Quality Monitor';
            app.AirQualityMonitorUIFigure.Icon = 'gauge.png';
            app.GridLayout = uigridlayout(app.AirQualityMonitorUIFigure);
            app.AirQualityMonitorLabel = uilabel(app.GridLayout);
            app.AirQualityMonitorLabel.Text = 'Air Quality Monitor';
            app.LocalCurrentConditionsPanel = uipanel(app.GridLayout);
            app.LocalCurrentConditionsPanel.Title = 'Local Current Conditions';
            app.GridLayout2 = uigridlayout(app.LocalCurrentConditionsPanel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.LocationUSCityEditFieldLabel = uilabel(app.GridLayout3);
            app.LocationUSCityEditFieldLabel.Text = 'Location (US City):';
            app.LocationUSCityEditField = uieditfield(app.GridLayout3, 'text');
            app.UpdateButton = uibutton(app.GridLayout3, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.AirqualityLabel = uilabel(app.GridLayout3);
            app.AirqualityLabel.Visible = 'off';
            app.AirqualityLabel.Text = 'Air quality:';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Temperature'; 'Pressure'; 'DewPoint'; 'RelativeHumidity'; 'WindDir'; 'Wind Spd'};
            app.UITable.RowName = {};
            app.DaysForecastPanel = uipanel(app.GridLayout);
            app.DaysForecastPanel.Title = '10 Days Forecast';
            app.DaysForecastPanel.Visible = 'off';
            % Show the figure after all components are created
            app.AirQualityMonitorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AirQuality

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AirQualityMonitorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AirQualityMonitorUIFigure)
        end
    end
end